classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        Ejercicio1Button   matlab.ui.control.Button
        Ejercicio1bButton  matlab.ui.control.Button
        Ejercicio2Button   matlab.ui.control.Button
        Ejercicio3Button   matlab.ui.control.Button
        CenterPanel        matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        RightPanel         matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Ejercicio1Button
        function Boton1(app, event)
            mainHH = mainHH_Euler_45913106;
            
            plot(app.UIAxes,mainHH.t,mainHH.V,'LineWidth',2)
            legend(app.UIAxes,'Action Potential');
            xlabel(app.UIAxes,'Time (ms)','FontWeight','bold') 
            ylabel(app.UIAxes,'Voltage (mV)','FontWeight','bold') 
            title(app.UIAxes,'Voltage Change for Hodgkin-Huxley Model','FontWeight','bold') 
%             set(app.UIAxes,gca,'FontSize',12)
%             set(app.UIAxes,gca,'FontWeight','bold')  
            
            plot(app.UIAxes2,mainHH.t(1:length(mainHH.INa)),mainHH.INa,'g',mainHH.t(1:length(mainHH.INa)),mainHH.IK,'r',mainHH.t(1:length(mainHH.INa)),mainHH.Il,'k','LineWidth',2)
            ylabel(app.UIAxes2,'Ion channel currents','FontWeight','bold') 
            xlabel(app.UIAxes2,'Time (ms)','FontWeight','bold') 
            %axis([0 5 0 1])
            legend(app.UIAxes2,'INa','IK','Ir');
            title(app.UIAxes2,'Ion Channel Currents','FontWeight','bold') 
%             set(gca,'FontSize',12)
%             set(gca,'FontWeight','bold')
        end

        % Button pushed function: Ejercicio1bButton
        function Ejercicio1bButtonPushed(app, event)
            mainHH_12 = mainHH_Euler_12ps_45913106;
            
            plot(app.UIAxes,mainHH_12.t,mainHH_12.V,'LineWidth',2)
            legend(app.UIAxes,'Action Potential');
            xlabel(app.UIAxes,'Time (ms)','FontWeight','bold') 
            ylabel(app.UIAxes,'Voltage (mV)','FontWeight','bold') 
            title(app.UIAxes,'Voltage Change for Hodgkin-Huxley Model 12ps','FontWeight','bold') 
%             set(app.UIAxes,gca,'FontSize',12)
%             set(app.UIAxes,gca,'FontWeight','bold')  
            
            plot(app.UIAxes2,mainHH_12.t(1:length(mainHH_12.INa)),mainHH_12.INa,'g',mainHH_12.t(1:length(mainHH_12.INa)),mainHH_12.IK,'r',mainHH_12.t(1:length(mainHH_12.INa)),mainHH_12.Il,'k','LineWidth',2)
            ylabel(app.UIAxes2,'Ion channel currents','FontWeight','bold') 
            xlabel(app.UIAxes2,'Time (ms)','FontWeight','bold') 
            %axis([0 5 0 1])
            legend(app.UIAxes2,'INa','IK','Ir');
            title(app.UIAxes2,'Ion Channel Currents 12ps','FontWeight','bold') 
%             set(gca,'FontSize',12)
%             set(gca,'FontWeight','bold')
        end

        % Button pushed function: Ejercicio2Button
        function Ejercicio2ButtonPushed(app, event)

            [cont,alc,caf]=Simulaalcoholycafeina_45913106;
            
            plot(app.UIAxes,alc.t,alc.V,cont.t,cont.V,caf.t,caf.V,'LineWidth',2)
            legend(app.UIAxes,'Alcohol','Control','Cafeina');
            xlabel(app.UIAxes,'Time (ms)','FontWeight','bold') 
            ylabel(app.UIAxes,'Voltage (mV)','FontWeight','bold') 
            title(app.UIAxes,'Voltage Change for Hodgkin-Huxley Model','FontWeight','bold') 
            % set(gca,'FontSize',12)
            % set(gca,'FontWeight','bold') 
            cla(app.UIAxes2,'reset')
            
        end

        % Button pushed function: Ejercicio3Button
        function Ejercicio3ButtonPushed(app, event)
            elect = calculaelectromiogramas_45913106(30,5);


            plot(app.UIAxes,1:elect.t,elect.AP_sano,1:elect.t,elect.AP_isch,'LineWidth',2)
            legend(app.UIAxes,'sano','isch');
            xlabel(app.UIAxes,'Time (ms)','FontWeight','bold') 
            ylabel(app.UIAxes,'Voltage (mV)','FontWeight','bold') 
            title(app.UIAxes,'Potenciales de acción','FontWeight','bold') 


            plot(app.UIAxes2,1:elect.t,elect.EGM_sano,1:elect.t,elect.EGM_trans,1:elect.t,elect.EGM_isch,'LineWidth',2)
            legend(app.UIAxes2,'sano','trans','isch');
            xlabel(app.UIAxes2,'Time (ms)','FontWeight','bold') 
            ylabel(app.UIAxes2,'Voltage (mV)','FontWeight','bold') 
            title(app.UIAxes2,'Electromiograma comparados','FontWeight','bold') 

%             
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {691, 691, 691};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {691, 691};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {142, '1x', 107};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Ejercicio1Button = uibutton(app.LeftPanel, 'push');
            app.Ejercicio1Button.ButtonPushedFcn = createCallbackFcn(app, @Boton1, true);
            app.Ejercicio1Button.Text = 'Ejercicio 1';
            app.Ejercicio1bButton = uibutton(app.LeftPanel, 'push');
            app.Ejercicio1bButton.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio1bButtonPushed, true);
            app.Ejercicio1bButton.Text = 'Ejercicio 1b';
            app.Ejercicio2Button = uibutton(app.LeftPanel, 'push');
            app.Ejercicio2Button.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio2ButtonPushed, true);
            app.Ejercicio2Button.Text = 'Ejercicio 2';
            app.Ejercicio3Button = uibutton(app.LeftPanel, 'push');
            app.Ejercicio3Button.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio3ButtonPushed, true);
            app.Ejercicio3Button.Text = 'Ejercicio 3';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end